classdef Five_component_fluid < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        Panel                        matlab.ui.container.Panel
        SelectspciesDropDownLabel    matlab.ui.control.Label
        Selectspcies                 matlab.ui.control.DropDown
        NSpinnerLabel                matlab.ui.control.Label
        N                            matlab.ui.control.Spinner
        TSpinnerLabel                matlab.ui.control.Label
        T                            matlab.ui.control.Spinner
        VbSpinnerLabel               matlab.ui.control.Label
        Vb                           matlab.ui.control.Spinner
        ResEditFieldLabel            matlab.ui.control.Label
        N_Res                        matlab.ui.control.NumericEditField
        ResEditField_2Label          matlab.ui.control.Label
        T_Res                        matlab.ui.control.NumericEditField
        ResEditField_3Label          matlab.ui.control.Label
        Vb_Res                       matlab.ui.control.NumericEditField
        UITable                      matlab.ui.control.Table
        CopyrightsCAjayLotekarLabel  matlab.ui.control.Label
        NonlinearTheoryGUI4componentplasmaLabel  matlab.ui.control.Label
        Panel_2                      matlab.ui.container.Panel
        MSpinnerLabel                matlab.ui.control.Label
        Mec                          matlab.ui.control.Spinner
        ResEditFieldLabel_2          matlab.ui.control.Label
        Res_M                        matlab.ui.control.NumericEditField
        StartEditFieldLabel          matlab.ui.control.Label
        Start_phi                    matlab.ui.control.NumericEditField
        EndEditFieldLabel            matlab.ui.control.Label
        End_phi                      matlab.ui.control.NumericEditField
        dphEditFieldLabel            matlab.ui.control.Label
        dph_phi                      matlab.ui.control.NumericEditField
        InputsforPhiLabel            matlab.ui.control.Label
        SaveInputButton              matlab.ui.control.Button
        SolutionButton               matlab.ui.control.Button
        UnormalizationInputPanel     matlab.ui.container.Panel
        ThEditFieldLabel             matlab.ui.control.Label
        Th_unNorm                    matlab.ui.control.NumericEditField
        N0EditFieldLabel             matlab.ui.control.Label
        N0_unNorm                    matlab.ui.control.NumericEditField
        Norm_flag                    matlab.ui.control.CheckBox
        eVLabel                      matlab.ui.control.Label
        cm3Label                     matlab.ui.control.Label
        Panel_3                      matlab.ui.container.Panel
        LxEditFieldLabel             matlab.ui.control.Label
        Lx                           matlab.ui.control.NumericEditField
        dxEditFieldLabel             matlab.ui.control.Label
        dx                           matlab.ui.control.NumericEditField
        MSGTextAreaLabel             matlab.ui.control.Label
        MSGTextArea                  matlab.ui.control.TextArea
        Panel_4                      matlab.ui.container.Panel
        EEditFieldLabel              matlab.ui.control.Label
        E_norm                       matlab.ui.control.NumericEditField
        WEditFieldLabel              matlab.ui.control.Label
        W_norm                       matlab.ui.control.NumericEditField
        VEditFieldLabel              matlab.ui.control.Label
        V_norm                       matlab.ui.control.NumericEditField
        E_unit                       matlab.ui.control.EditField
        W_unit                       matlab.ui.control.EditField
        V_unit                       matlab.ui.control.EditField
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global Nce Tce vbce res_Nce res_Tce res_vbce Species choice
            global Nwce Twce vbwce res_Nwce res_Twce res_vbwce
            global Nwe Twe vbwe res_Nwe res_Twe res_vbwe
            global Nhe The vbhe res_Nhe res_The res_vbhe
            global Ni Ti vbi res_Ni res_Ti res_vbi
            global M res_M lf rf  dph
            
            
            Nce = 0.025; % Cold electron desity
            Tce = 0.1724;  % Cold electron temprature
            vbce = 0;    % Cold electron beam
            res_Nce = 0.001;
            res_Tce = 0.001;
            res_vbce = 0.001;
            
            Nwce = 0.4631; % warm-Cold electron desity
            Twce = 0.1839;  % warm-Cold electron temprature
            vbwce = 0;    % warm-cold electron beam
            res_Nwce = 0.001;
            res_Twce = 0.001;
            res_vbwce = 0.001;
            
            Nwe = 0.4842; % warm electron desity
            Twe = 0.2413;  % warm electron temprature
            vbwe = 0;    % warm electron beam
            res_Nwe = 0.001;
            res_Twe = 0.001;
            res_vbwe = 0.001;
            
            Nhe = 1-Nce-Nwce-Nwe; % warm electron desity
            The = 1;  % warm electron temprature
            vbhe = 0;    % warm electron beam
            res_Nhe = 0.001;
            res_The = 0.001;
            res_vbhe = 0.001;
            
            Ni = 1; % warm electron desity
            Ti = 0.001;  % warm electron temprature
            vbi = 0;    % warm electron beam
            res_Ni = 0.001;
            res_Ti = 0.001;
            res_vbi = 0.001;
            
            M = 0.7986;
            res_M = 0.0001;
            lf = 0%-0.5*(M-sqrt(3*Tce))^2;
            rf = 0.5*(M-sqrt(3*Tce))^2;
            dph = 0.0001;
            app.Th_unNorm.Value = 87;
            app.N0_unNorm.Value = 47.5;
            app.Lx.Value = 100;
            app.dx.Value = 0.01;
            Species = {'Cold Electron'; 'Warm cold Electron'; 'Warm Electron'; 'Hot Electron'; 'Ion'}
            Dens   = num2cell([Nce; Nwce; Nwe; Nhe; Ni]);
            Temp   = num2cell([Tce; Twce; Twe; The; Ti]);
            beam   = num2cell([vbce; vbwce; vbwe; vbhe; vbi]);
            app.UITable.Data = [Species Dens Temp beam];
            app.N.Value = Nce;
            app.T.Value = Tce;
            app.Vb.Value = vbce;
            app.N.Step = res_Nce;
            app.N_Res.Value = res_Nce;
            app.T.Step = res_Tce;
            app.T_Res.Value = res_Tce;
            app.Vb.Step = res_vbce;
            app.Vb_Res.Value = res_vbce;
            app.Mec.Value = M;
            app.Mec.Step = res_M
            app.Res_M.Value=res_M;
            app.Start_phi.Value = lf;
            app.End_phi.Value = rf;
            app.dph_phi.Value = dph;
            choice = "Cold Electron ";
        end

        % Value changing function: N
        function NValueChanging(app, event)
            global Nce Tce vbce Species choice
            global Nwce Twce vbwce
            global Nwe Twe vbwe
            global Nhe The vbhe
            global Ni Ti vbi
            global M res_M lf rf  dph
            
            
            changingValue = event.Value;
            if choice == "Cold Electron "
                Nce = changingValue;
            elseif choice =="warm-cold Electron"
                Nwce = changingValue;
            elseif choice =="Warm Electron"
                Nwe = changingValue;
            end
            Nhe = 1-Nce-Nwce-Nwe;
            Dens   = num2cell([Nce; Nwce; Nwe; Nhe; Ni]);
            Temp   = num2cell([Tce; Twce; Twe; The; Ti]);
            beam   = num2cell([vbce; vbwce; vbwe; vbhe; vbi]);
            app.UITable.Data = [Species Dens Temp beam];
            Sv_plot
        end

        % Value changing function: T
        function TValueChanging(app, event)
            global Nce Tce vbce Species choice
            global Nwce Twce vbwce
            global Nwe Twe vbwe
            global Nhe The vbhe
            global Ni Ti vbi
            global M res_M lf rf  dph
            
            
            changingValue = event.Value;
            if choice == "Cold Electron "
                Tce = changingValue;
            elseif choice =="warm-cold Electron"
                Twce = changingValue;
            elseif choice =="Warm Electron"
                Twe = changingValue;
            elseif choice =="Ion"
                Ti = changingValue;
            end
            The = 1;
            Dens   = num2cell([Nce; Nwce; Nwe; Nhe; Ni]);
            Temp   = num2cell([Tce; Twce; Twe; The; Ti]);
            beam   = num2cell([vbce; vbwce; vbwe; vbhe; vbi]);
            app.UITable.Data = [Species Dens Temp beam];
            Sv_plot
        end

        % Value changed function: N_Res
        function N_ResValueChanged(app, event)
            global res_Nce res_Nwce res_Nwe res_Nhe res_Ni choice
            value = app.N_Res.Value;
            app.N.Step = value;
            if choice == "Cold Electron "
                res_Nce = value;
            elseif choice =="warm-cold Electron"
                res_Nwce = value;
            elseif choice =="Warm Electron"
                res_Nwe = value;
            elseif choice =="Hot Electron"
                res_Nhe = value;
            elseif choice =="Ion"
                res_Ni = value;
            end
        end

        % Value changed function: T_Res
        function T_ResValueChanged(app, event)
            global res_Tce res_Twce res_Twe res_The res_Ti choice
            value = app.T_Res.Value;
            app.T.Step = value;
            if choice == "Cold Electron "
                res_Tce = value;
            elseif choice =="warm-cold Electron"
                res_Twce = value;
            elseif choice =="Warm Electron"
                res_Twe = value;
            elseif choice =="Hot Electron"
                res_The = value;
            elseif choice =="Ion"
                res_Ti = value;
            end
        end

        % Value changing function: Vb
        function VbValueChanging(app, event)
            global Nce Tce vbce Species choice
            global Nwce Twce vbwce
            global Nwe Twe vbwe
            global Nhe The vbhe
            global Ni Ti vbi
            global M res_M lf rf  dph
            
            
            changingValue = event.Value;
            
            if choice == "Cold Electron "
                vbce = changingValue;
            elseif choice =="warm-cold Electron"
                vbwce = changingValue;
            elseif choice =="Warm Electron"
                vbwe = changingValue;
            elseif choice =="Hot Electron"
                vbhe = changingValue;
            elseif choice =="Ion"
                vbi = changingValue;
            end
            
            Dens   = num2cell([Nce; Nwce; Nwe; Nhe; Ni]);
            Temp   = num2cell([Tce; Twce; Twe; The; Ti]);
            beam   = num2cell([vbce; vbwce; vbwe; vbhe; vbi]);
            app.UITable.Data = [Species Dens Temp beam];
            Sv_plot
        end

        % Value changed function: Vb_Res
        function Vb_ResValueChanged(app, event)
            global res_vbce res_vbwce res_vbwe res_vbhe res_vbi choice
            value = app.Vb_Res.Value;
            if choice == "Cold Electron "
                app.Vb.Step = value;
                res_vbce = value;
            elseif choice =="warm-cold Electron"
                app.Vb.Step = value;
                res_vbwce = value;
            elseif choice =="warm-cold Electron"
                app.Vb.Step = value;
                res_vbwe = value;
            elseif choice =="warm-cold Electron"
                app.Vb.Step = value;
                res_vbhe = value;
            elseif choice =="Ion"
                app.Vb.Step = value;
                res_vbi = value;
            end
            
        end

        % Value changed function: Selectspcies
        function SelectspciesValueChanged(app, event)
            global Nce Tce vbce res_Nce res_Tce res_vbce Species choice
            global Nwce Twce vbwce res_Nwce res_Twce res_vbwce
            global Nwe Twe vbwe res_Nwe res_Twe res_vbwe
            global Nhe The vbhe res_Nhe res_The res_vbhe
            global Ni Ti vbi res_Ni res_Ti res_vbi
            
            choice = app.Selectspcies.Value;
            disp(choice)
            if choice == "Cold Electron "
                app.N.Value = Nce;
                app.T.Value = Tce;
                app.Vb.Value = vbce;
                app.N.Step = res_Nce;
                app.N_Res.Value = res_Nce;
                app.T.Step = res_Tce;
                app.T_Res.Value = res_Tce;
                app.Vb.Step = res_vbce;
                app.Vb_Res.Value = res_vbce;
            elseif choice =="warm-cold Electron"
                app.N.Value = Nwce;
                app.T.Value = Twce;
                app.Vb.Value = vbwce;
                app.N.Step = res_Nwce;
                app.N_Res.Value = res_Nwce;
                app.T.Step = res_Twce;
                app.T_Res.Value = res_Twce;
                app.Vb.Step = res_vbwce;
                app.Vb_Res.Value = res_vbwce;
            elseif choice =="Warm Electron"
                app.N.Value = Nwe;
                app.T.Value = Twe;
                app.Vb.Value = vbwe;
                app.N.Step = res_Nwe;
                app.N_Res.Value = res_Nwe;
                app.T.Step = res_Twe;
                app.T_Res.Value = res_Twe;
                app.Vb.Step = res_vbwe;
                app.Vb_Res.Value = res_vbwe;
            elseif choice =="Hot Electron"
                app.N.Value = Nhe;
                app.T.Value = The;
                app.Vb.Value = vbhe;
                app.N.Step = res_Nhe;
                app.N_Res.Value = res_Nhe;
                app.T.Step = res_The;
                app.T_Res.Value = res_The;
                app.Vb.Step = res_vbhe;
                app.Vb_Res.Value = res_vbhe;
            elseif choice =="Ion"
                app.N.Value = Ni;
                app.T.Value = Ti;
                app.Vb.Value = vbi;
                app.N.Step = res_Ni;
                app.N_Res.Value = res_Ni;
                app.T.Step = res_Ti;
                app.T_Res.Value = res_Ti;
                app.Vb.Step = res_vbi;
                app.Vb_Res.Value = res_vbi;
            end
        end

        % Value changing function: Mec
        function MecValueChanging(app, event)
            global Nce Tce vbce
            global Nwce Twce vbwce
            global Nwe Twe vbwe
            global Nhe The vbhe
            global Ni Ti vbi
            global M res_M lf rf  dph
            
            changingValue = event.Value;
            M = changingValue
            Sv_plot
        end

        % Value changed function: Res_M
        function Res_MValueChanged(app, event)
            global res_M
            value = app.Res_M.Value;
            app.Mec.Step = value;
            res_M = value;
        end

        % Value changed function: Start_phi
        function Start_phiValueChanged(app, event)
            global lf
            value = app.Start_phi.Value;
            lf = value;
            
        end

        % Value changed function: End_phi
        function End_phiValueChanged(app, event)
            global rf
            value = app.End_phi.Value;
            rf = value;
        end

        % Value changed function: dph_phi
        function dph_phiValueChanged(app, event)
            global dph
            value = app.dph_phi.Value;
            dph = value;
        end

        % Callback function
        function plot_handleSelectionChanged(app, event)
            global tlbr
            selectedButton = app.plot_handle.SelectedObject;
            
            if  selectedButton.Text == "Zoom in"
                tlbr = 1;
            elseif selectedButton.Text == "Pan"
                tlbr = 3;
            end
            Sv_plot
        end

        % Button pushed function: SaveInputButton
        function SaveInputButtonPushed(app, event)
            global Nce Tce vbce 
            global Nwce Twce vbwce 
            global Nwe Twe vbwe 
            global Nhe The vbhe 
            global Ni Ti vbi
            global M res_M lf rf  dph tlbr
            Input_parameters_save
        end

        % Button pushed function: SolutionButton
        function SolutionButtonPushed(app, event)
            Solution_script
        end

        % Value changed function: Norm_flag
        function Norm_flagValueChanged(app, event)
            value = app.Norm_flag.Value;

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sagdeev psudo potential ')
            xlabel(app.UIAxes, '\Phi')
            ylabel(app.UIAxes, 'S(\Phi)')
            app.Panel = uipanel(app.UIFigure);
            app.SelectspciesDropDownLabel = uilabel(app.Panel);
            app.SelectspciesDropDownLabel.Text = 'Select spcies';
            app.Selectspcies = uidropdown(app.Panel);
            app.Selectspcies.Items = {'Cold Electron ', 'warm-cold Electron', 'Warm Electron', 'Hot Electron', 'Ion'};
            app.Selectspcies.ValueChangedFcn = createCallbackFcn(app, @SelectspciesValueChanged, true);
            app.Selectspcies.Value = 'Cold Electron ';
            app.NSpinnerLabel = uilabel(app.Panel);
            app.NSpinnerLabel.Text = 'N';
            app.N = uispinner(app.Panel);
            app.N.ValueChangingFcn = createCallbackFcn(app, @NValueChanging, true);
            app.TSpinnerLabel = uilabel(app.Panel);
            app.TSpinnerLabel.Text = 'T';
            app.T = uispinner(app.Panel);
            app.T.ValueChangingFcn = createCallbackFcn(app, @TValueChanging, true);
            app.VbSpinnerLabel = uilabel(app.Panel);
            app.VbSpinnerLabel.Text = 'Vb';
            app.Vb = uispinner(app.Panel);
            app.Vb.ValueChangingFcn = createCallbackFcn(app, @VbValueChanging, true);
            app.ResEditFieldLabel = uilabel(app.Panel);
            app.ResEditFieldLabel.Text = 'Res';
            app.N_Res = uieditfield(app.Panel, 'numeric');
            app.N_Res.ValueChangedFcn = createCallbackFcn(app, @N_ResValueChanged, true);
            app.ResEditField_2Label = uilabel(app.Panel);
            app.ResEditField_2Label.Text = 'Res';
            app.T_Res = uieditfield(app.Panel, 'numeric');
            app.T_Res.ValueChangedFcn = createCallbackFcn(app, @T_ResValueChanged, true);
            app.ResEditField_3Label = uilabel(app.Panel);
            app.ResEditField_3Label.Text = 'Res';
            app.Vb_Res = uieditfield(app.Panel, 'numeric');
            app.Vb_Res.ValueChangedFcn = createCallbackFcn(app, @Vb_ResValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Species'; 'Density'; 'Temp.'; 'vb'};
            app.UITable.RowName = {};
            app.CopyrightsCAjayLotekarLabel = uilabel(app.UIFigure);
            app.CopyrightsCAjayLotekarLabel.Text = 'Copyrights (C) Ajay Lotekar ';
            app.NonlinearTheoryGUI4componentplasmaLabel = uilabel(app.UIFigure);
            app.NonlinearTheoryGUI4componentplasmaLabel.Text = 'Non-linear Theory GUI: 4-component plasma ';
            app.Panel_2 = uipanel(app.UIFigure);
            app.MSpinnerLabel = uilabel(app.Panel_2);
            app.MSpinnerLabel.Text = 'M';
            app.Mec = uispinner(app.Panel_2);
            app.Mec.ValueChangingFcn = createCallbackFcn(app, @MecValueChanging, true);
            app.ResEditFieldLabel_2 = uilabel(app.Panel_2);
            app.ResEditFieldLabel_2.Text = 'Res';
            app.Res_M = uieditfield(app.Panel_2, 'numeric');
            app.Res_M.ValueChangedFcn = createCallbackFcn(app, @Res_MValueChanged, true);
            app.StartEditFieldLabel = uilabel(app.Panel_2);
            app.StartEditFieldLabel.Text = 'Start';
            app.Start_phi = uieditfield(app.Panel_2, 'numeric');
            app.Start_phi.ValueChangedFcn = createCallbackFcn(app, @Start_phiValueChanged, true);
            app.EndEditFieldLabel = uilabel(app.Panel_2);
            app.EndEditFieldLabel.Text = 'End';
            app.End_phi = uieditfield(app.Panel_2, 'numeric');
            app.End_phi.ValueChangedFcn = createCallbackFcn(app, @End_phiValueChanged, true);
            app.dphEditFieldLabel = uilabel(app.Panel_2);
            app.dphEditFieldLabel.Text = 'dph';
            app.dph_phi = uieditfield(app.Panel_2, 'numeric');
            app.dph_phi.ValueChangedFcn = createCallbackFcn(app, @dph_phiValueChanged, true);
            app.InputsforPhiLabel = uilabel(app.Panel_2);
            app.InputsforPhiLabel.Text = 'Inputs for Phi';
            app.SaveInputButton = uibutton(app.UIFigure, 'push');
            app.SaveInputButton.ButtonPushedFcn = createCallbackFcn(app, @SaveInputButtonPushed, true);
            app.SaveInputButton.Text = 'Save Input';
            app.SolutionButton = uibutton(app.UIFigure, 'push');
            app.SolutionButton.ButtonPushedFcn = createCallbackFcn(app, @SolutionButtonPushed, true);
            app.SolutionButton.Text = 'Solution';
            app.UnormalizationInputPanel = uipanel(app.UIFigure);
            app.UnormalizationInputPanel.Title = 'Unormalization Input';
            app.ThEditFieldLabel = uilabel(app.UnormalizationInputPanel);
            app.ThEditFieldLabel.Text = 'Th';
            app.Th_unNorm = uieditfield(app.UnormalizationInputPanel, 'numeric');
            app.N0EditFieldLabel = uilabel(app.UnormalizationInputPanel);
            app.N0EditFieldLabel.Text = 'N0';
            app.N0_unNorm = uieditfield(app.UnormalizationInputPanel, 'numeric');
            app.Norm_flag = uicheckbox(app.UnormalizationInputPanel);
            app.Norm_flag.ValueChangedFcn = createCallbackFcn(app, @Norm_flagValueChanged, true);
            app.Norm_flag.Text = 'yes';
            app.eVLabel = uilabel(app.UnormalizationInputPanel);
            app.eVLabel.Text = 'eV';
            app.cm3Label = uilabel(app.UnormalizationInputPanel);
            app.cm3Label.Text = 'cm-3';
            app.Panel_3 = uipanel(app.UIFigure);
            app.LxEditFieldLabel = uilabel(app.Panel_3);
            app.LxEditFieldLabel.Text = 'Lx';
            app.Lx = uieditfield(app.Panel_3, 'numeric');
            app.dxEditFieldLabel = uilabel(app.Panel_3);
            app.dxEditFieldLabel.Text = 'dx';
            app.dx = uieditfield(app.Panel_3, 'numeric');
            app.MSGTextAreaLabel = uilabel(app.UIFigure);
            app.MSGTextAreaLabel.Text = 'MSG:';
            app.MSGTextArea = uitextarea(app.UIFigure);
            app.Panel_4 = uipanel(app.UIFigure);
            app.EEditFieldLabel = uilabel(app.Panel_4);
            app.EEditFieldLabel.Text = 'E';
            app.E_norm = uieditfield(app.Panel_4, 'numeric');
            app.WEditFieldLabel = uilabel(app.Panel_4);
            app.WEditFieldLabel.Text = 'W';
            app.W_norm = uieditfield(app.Panel_4, 'numeric');
            app.VEditFieldLabel = uilabel(app.Panel_4);
            app.VEditFieldLabel.Text = 'V';
            app.V_norm = uieditfield(app.Panel_4, 'numeric');
            app.E_unit = uieditfield(app.Panel_4, 'text');
            app.W_unit = uieditfield(app.Panel_4, 'text');
            app.V_unit = uieditfield(app.Panel_4, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Five_component_fluid

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end